classdef CANopenEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EDSButton           matlab.ui.control.Button
        CANopenEditorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        old_file_path = ''; % old EDS file path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: EDSButton
        function EDSButtonPushed(app, event)
            %
            % Open a dialog window and let the user select an EDS file.
            %
            % param [in] block           Block name.
            % param [in] parameter       Parameter name.
            %
                % ============= CONFIG SECTION ================================= %
                %   If some names in the block mask change modify here as well   %
                
                % ------------------ ttDescriptions ---------------------------- %
                ttParamWindow = '';
                % ============================================================== %
            
                % get old eds path in order to restart from there
                try
                    if isfile(app.old_file_path)
                        [ttParamWindow, ~] = fileparts(app.old_file_path);
                    end
                catch
                    disp(['Error in reading', 32, parameter, 32, 'parameter!']);
                end
                
                % open new window for eds file selection
                [file, path] = uigetfile(fullfile(ttParamWindow, '*.eds'), 'Select EDS file');
                eds = char(fullfile(path, file));
                
                % check if selected file exists and update path
                if isfile(eds)
                    try
                        app.old_file_path = eds;
                        CoGui(eds);
                        delete(app);
                    catch
                        error('Error in reading EDS file!');
                    end
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CANopenEditorLabel = uilabel(app.UIFigure);
            app.CANopenEditorLabel.Text = 'CANopen Editor';
            app.EDSButton = uibutton(app.UIFigure, 'push');
            app.EDSButton.ButtonPushedFcn = createCallbackFcn(app, @EDSButtonPushed, true);
            app.EDSButton.Text = 'EDS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CANopenEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end